classdef Si_count < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SicountRobotUIFigure  matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        Label                 matlab.ui.control.Label
        DropDownp             matlab.ui.control.DropDown
        Button                matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Label_2               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        Button_4              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global img;
            global file;
            [file,path] = uigetfile( ...
                {'*.jpg*',  'JPG img Files (*.jpg*)'}, ...
                 'Select an Image File','.\1.jpg');
            if ~isequal(file,0)
               img = imread(strcat([path,file]));
               img = img(:,:,1);
               switch app.DropDownp.Value
                   case "高斯噪声"
                       img = imnoise(img,'gaussian');
                   case "椒盐噪声"
                       img = imnoise(img,'salt & pepper');
                   case "散粒噪声"
                       img = imnoise(img,'speckle');
                   otherwise
               end
               imshow(img,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global img;
            if ~isempty(img)
            f = waitbar(0.2,'processing image...');
            [si,img_out] = Si_counter(img);
            waitbar(0.8,f,'creating mark...');
            si_loc = (si.end(:)+si.start(:))/2;
            [m,n] = size(img_out);
            imshow(img_out,'Parent',app.UIAxes2);hold(app.UIAxes2,'on');
            for k = 1:length(si.ind)
                % 绘制第k条直线
                plot(app.UIAxes2, [si_loc(k),si_loc(k)],[ceil(m/3),floor(2*m/3)],'LineWidth',2,'Color','green');
            end
            text(app.UIAxes2, ceil(n/10),ceil(m/10),strcat(['total num:',num2str(length(si.ind))]),...
                'Color','green','FontSize',24,"FontWeight","bold");
            hold(app.UIAxes2,'off');
            waitbar(1,f,'finished');
            pause(0.1);
            close(f);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global file;
            [s_file,s_path] = uiputfile( ...
                            {'*.jpg*',  'JPG img Files (*.jpg*)'}, ...
                             'Enter a File name',strcat(['count_',file]));
             if ~isequal(s_file,0)
             f = waitbar(0.2,'saving Image...');
             exportgraphics(app.UIAxes2,strcat([s_path,s_file]),'resolution',500,'backgroundcolor',[0,0,0])
             waitbar(1,f,'finished');
             pause(0.1);close(f);
             end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            [batch_file,batch_path] = uigetfile( ...
                {'*.jpg*',  'JPG img Files (*.jpg*)'}, ...
                 'Select an Image File','.\1.jpg','MultiSelect', 'on');
            if ~isequal(batch_file,0)
            counter = zeros([1,length(batch_file)]);
            f = waitbar(0,"loading image...");
            file_num = length(batch_file);
            for i=1:file_num
                waitbar(i/file_num,f,strcat(['processing image: ',num2str(i),'/',num2str(file_num)]));
                img = imread(strcat([batch_path,batch_file{i}]));
                [si,~] = Si_counter(img);
                counter(i) = length(si.ind);
            end
            waitbar(1,f,"creating Excel file...")
            [s_file,s_path] = uiputfile( ...
                            {'*.xlsx*',  'Excel Files (*.xlsx*)'}, ...
                             'Enter a File name','batch_count.xlsx');
            if ~isequal(s_file,0)
                if exist(strcat([s_path,s_file]),'file') == 2
                    delete(strcat([s_path,s_file]));
                end
                xlswrite(strcat([s_path,s_file]),...
                    [['file name',batch_file];['Si num',num2cell(counter)]]',...
                    1,'A1');
            end
            waitbar(1,f,"finished");
            pause(0.2);
            close(f);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SicountRobotUIFigure = uifigure('Visible', 'off');
            app.SicountRobotUIFigure.Name = 'Si count Robot';
            app.UIAxes = uiaxes(app.SicountRobotUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[]'};
            app.UIAxes2 = uiaxes(app.SicountRobotUIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = {'[]'};
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = {'[]'};
            app.Label = uilabel(app.SicountRobotUIFigure);
            app.Label.Text = '添加噪声';
            app.DropDownp = uidropdown(app.SicountRobotUIFigure);
            app.DropDownp.Items = {'无', '高斯噪声', '椒盐噪声', '散粒噪声'};
            app.DropDownp.Value = '无';
            app.Button = uibutton(app.SicountRobotUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '读取图片';
            app.Button_2 = uibutton(app.SicountRobotUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '计数';
            app.Button_3 = uibutton(app.SicountRobotUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '保存结果';
            app.Label_2 = uilabel(app.SicountRobotUIFigure);
            app.Label_2.Text = '输入图像';
            app.Label_3 = uilabel(app.SicountRobotUIFigure);
            app.Label_3.Text = '计数结果';
            app.Button_4 = uibutton(app.SicountRobotUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '批量处理';
            % Show the figure after all components are created
            app.SicountRobotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Si_count

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SicountRobotUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SicountRobotUIFigure)
        end
    end
end